classdef ProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SARTButton_2              matlab.ui.control.Button
        SARTButton                matlab.ui.control.Button
        FBPButton                 matlab.ui.control.Button
        FBPButton_2               matlab.ui.control.Button
        GenerateSinogramButton_2  matlab.ui.control.Button
        LoadCustomPhantomButton   matlab.ui.control.Button
        GenerateSinogramButton    matlab.ui.control.Button
        LoadSheppLoganButton      matlab.ui.control.Button
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSinogramButton
        function GenerateSinogramButtonPushed(app, event)
            I1 = imread('SheppLogan_Phantom.png');
            I = rgb2gray(I1);
            theta = 0:179;
            [R,xp] = radon(I,theta);
            imagesc(app.UIAxes,theta,xp,R);
            colormap(app.UIAxes,gray);

        end

        % Button pushed function: GenerateSinogramButton_2
        function GenerateSinogramButton_2Pushed(app, event)
            I1 = imread('discphantom.png');
            I = rgb2gray(I1);
            theta = 0:179;
            [R,xp] = radon(I,theta);
            imagesc(app.UIAxes2,theta,xp,R);
            colormap(app.UIAxes2,gray);
        end

        % Button pushed function: FBPButton_2
        function FBPButton_2Pushed(app, event)
            run('fbp_shepplogan.m')
        end

        % Button pushed function: FBPButton
        function FBPButtonPushed(app, event)
            run('fbp_discphantom.m')
        end

        % Button pushed function: LoadSheppLoganButton
        function LoadSheppLoganButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.png'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           imagesc(app.UIAxes3, ImageFile);
        end

        % Button pushed function: LoadCustomPhantomButton
        function LoadCustomPhantomButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.png'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           imagesc(app.UIAxes4, ImageFile);
        end

        % Button pushed function: SARTButton
        function SARTButtonPushed(app, event)
            run('sart_shepplogan.m')
        end

        % Button pushed function: SARTButton_2
        function SARTButton_2Pushed(app, event)
            run('sart_discphantom.m')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Shepp Logan Sinogram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Custom Phantom Sinogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Shepp Logan Phan')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Custom Virtual Phantom')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes4.Visible = 'off';
            app.LoadSheppLoganButton = uibutton(app.UIFigure, 'push');
            app.LoadSheppLoganButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSheppLoganButtonPushed, true);
            app.LoadSheppLoganButton.Text = 'Load Shepp Logan';
            app.GenerateSinogramButton = uibutton(app.UIFigure, 'push');
            app.GenerateSinogramButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSinogramButtonPushed, true);
            app.GenerateSinogramButton.Text = 'Generate Sinogram';
            app.LoadCustomPhantomButton = uibutton(app.UIFigure, 'push');
            app.LoadCustomPhantomButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomPhantomButtonPushed, true);
            app.LoadCustomPhantomButton.Text = 'Load Custom Phantom';
            app.GenerateSinogramButton_2 = uibutton(app.UIFigure, 'push');
            app.GenerateSinogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateSinogramButton_2Pushed, true);
            app.GenerateSinogramButton_2.Text = 'Generate Sinogram';
            app.FBPButton_2 = uibutton(app.UIFigure, 'push');
            app.FBPButton_2.ButtonPushedFcn = createCallbackFcn(app, @FBPButton_2Pushed, true);
            app.FBPButton_2.Text = 'FBP';
            app.FBPButton = uibutton(app.UIFigure, 'push');
            app.FBPButton.ButtonPushedFcn = createCallbackFcn(app, @FBPButtonPushed, true);
            app.FBPButton.Text = 'FBP';
            app.SARTButton = uibutton(app.UIFigure, 'push');
            app.SARTButton.ButtonPushedFcn = createCallbackFcn(app, @SARTButtonPushed, true);
            app.SARTButton.Text = 'SART';
            app.SARTButton_2 = uibutton(app.UIFigure, 'push');
            app.SARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @SARTButton_2Pushed, true);
            app.SARTButton_2.Text = 'SART';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end